classdef generateBrir < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Menu                      matlab.ui.container.Menu
        HomeMenu                  matlab.ui.container.Menu
        ProcessstimuliMenu        matlab.ui.container.Menu
        ExitMenu                  matlab.ui.container.Menu
        SelectDirectoryToSaveBRIRButton  matlab.ui.control.Button
        RoomDimentionLabel        matlab.ui.control.Label
        RoomDimentionLength       matlab.ui.control.NumericEditField
        RoomDimentionlWidth       matlab.ui.control.NumericEditField
        RoomDimentionlHeigth      matlab.ui.control.NumericEditField
        GenerateBRIRButton        matlab.ui.control.Button
        test1Button               matlab.ui.control.Button
        LengthLabel               matlab.ui.control.Label
        WidthLabel                matlab.ui.control.Label
        HeigthLabel               matlab.ui.control.Label
        RecieverCoordinatesLabel  matlab.ui.control.Label
        RecieverCoordsLength      matlab.ui.control.NumericEditField
        RecieverCoordsWidth       matlab.ui.control.NumericEditField
        RecieverCoordsHeigth      matlab.ui.control.NumericEditField
        Logo                      matlab.ui.control.Button
        AbsorptionLabel           matlab.ui.control.Label
        absorption1               matlab.ui.control.NumericEditField
        absorption2               matlab.ui.control.NumericEditField
        absorption3               matlab.ui.control.NumericEditField
        absorption4               matlab.ui.control.NumericEditField
        absorption5               matlab.ui.control.NumericEditField
        absorption6               matlab.ui.control.NumericEditField
        BearingLabel              matlab.ui.control.Label
        bearing                   matlab.ui.control.NumericEditField
        DistanceLabel             matlab.ui.control.Label
        distanceMax               matlab.ui.control.NumericEditField
        MaximumLabel              matlab.ui.control.Label
        distanceMin               matlab.ui.control.NumericEditField
        step                      matlab.ui.control.NumericEditField
        MinimumLabel              matlab.ui.control.Label
        StepLabel                 matlab.ui.control.Label
        IRLengthLabel             matlab.ui.control.Label
        irLength                  matlab.ui.control.NumericEditField
        brirGraph                 matlab.ui.control.UIAxes
        brirNum                   matlab.ui.control.Label
    end

    
    properties (Access = public)
        dirname % directory to save brir
    end
    

    methods (Access = private)

        % Callback function
        function BacktoMenuButtonPushed(app, event)
            SoundEngine;
            closereq;
        end

        % Button pushed function: SelectDirectoryToSaveBRIRButton
        function SelectDirectoryToSaveBRIRButtonPushed(app, event)
            title = 'Select Directory to save the BRIR';
            app.dirname = uigetdir('C:\', title) + ("\");
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
        end

        % Value changed function: RoomDimentionLength
        function RoomDimentionLengthValueChanged(app, event)
            roomLength = app.RoomDimentionLength.Value;
        end

        % Value changed function: RoomDimentionlWidth
        function RoomDimentionlWidthValueChanged(app, event)
            roomWidth = app.RoomDimentionlWidth.Value;
        end

        % Value changed function: RoomDimentionlHeigth
        function RoomDimentionlHeigthValueChanged(app, event)
            roomHeigth = app.RoomDimentionlHeigth.Value;
        end

        % Button pushed function: GenerateBRIRButton
        function GenerateBRIRButtonPushed(app, event)
            %get room dimentions
            roomLength = app.RoomDimentionLength.Value;
            roomWidth = app.RoomDimentionlWidth.Value;
            roomHeigth = app.RoomDimentionlHeigth.Value;
            dims = [roomWidth, roomLength, roomHeigth];
            %get reciever coords
            recieverCoordsLen = app.RecieverCoordsLength.Value;
            recieverCoordsWidth = app.RecieverCoordsWidth.Value;
            recieverCoordsHeigth = app.RecieverCoordsHeigth.Value;
            receiver_coords = [recieverCoordsWidth, recieverCoordsLen, recieverCoordsHeigth];
            %distance from receiver to source
            maxDistance = app.distanceMax.Value;
            minDistance = app.distanceMin.Value;
            steps = app.step.Value;
            
            %get absorption
            absorb1 = app.absorption1.Value; absorb2 = app.absorption2.Value; absorb3 = app.absorption3.Value;
            absorb4 = app.absorption4.Value; absorb5 = app.absorption5.Value; absorb6 = app.absorption6.Value;
            absorb = [absorb1, absorb2, absorb3, absorb4, absorb5, absorb6];
            %get bearing
            Bearing = app.bearing.Value;
            %get IR length
            ir_length = app.irLength.Value;
            
            %get Directory to save the files
            dir = app.dirname;
            
            %TEST only
%             fprintf("The dimentions of the room as : %d\n",  dims);
%             fprintf("the reciever coords are : %d\n",  receiver_coords);
%             fprintf("the room absorbtion is: %d\n", absorb);
%             fprintf("the bearing od the room is: %d\n", bearing);
%             fprintf("the ir chose is: %d\n", irLength);
%             fprintf("The dir selected is : %s\n", dir);
            
            %Generate brir
            for i = 0:floor((maxDistance-minDistance)/steps)
                
                dist_to_source = minDistance + i*steps;
                
                str_dist = mk_str_dist(dist_to_source); % write the distance as a string for use in the output file name.
                %current_brir = [i];
                x = receiver_coords(1);
                y = receiver_coords(2) + dist_to_source;
                z = receiver_coords(3);
                
                source_coords = [x y z];
                
                %generate BRIR (with default Fs=44100 Hz):
                brir = make_brir(dims,absorb,source_coords,receiver_coords,Bearing,ir_length);
                
                l = brir(:, 1);
                r = brir(:, 2);
                
                % downsample BRIR from 44100 to Fs = 22050 Hz (to match our stimuli):
                
                l_down = downsample(l,2);
                r_down = downsample(r,2);
                brir_down= [l_down,r_down];
                
                brir_down_scale = brir_down/150; % initial scaling
                
                if ( exist(dir,'dir')==0 )
                    disp('Please provide a valid input directory name.')
                else
                    filename= strcat(dir, 'brir_anech_', str_dist , '.wav');
                end % if
                
                for j = 1:length(brir_down_scale)
                    if(brir_down_scale(j) > 1 | brir_down_scale(j) < -1)
                        
                        brir_down_scale = brir_down_scale./(max(abs(brir_down_scale)));
                        
                    end %if
                end %for j
                
                audiowrite(filename,brir_down_scale,22050);
                
              %  brir_dir = app.dirname;
                
%                 fprintf("Generating BRIR number: %d\n", i+1);
                brirNumber = sprintf("Generating BRIR number %d out of %d", i+1, floor((maxDistance - minDistance)/steps)+1);
                app.brirNum.Text = brirNumber;
                
                %plot brir - test
                plot(app.brirGraph,brir_down_scale);
                
            end %for i 
            
        end

        % Button pushed function: test1Button
        function test1ButtonPushed(app, event)
            i = 5;
            textTest = sprintf("Generating BRIR number: %d", i);
            fprintf("test");
            app.brirNum.Text = textTest;
        end

        % Value changed function: RecieverCoordsLength
        function RecieverCoordsLengthValueChanged(app, event)
          %  value = app.RecieverCoordsLength.Value;
        end

        % Value changed function: RecieverCoordsWidth
        function RecieverCoordsWidthValueChanged(app, event)
           % value = app.RecieverCoordsWidth.Value;
        end

        % Value changed function: RecieverCoordsHeigth
        function RecieverCoordsHeigthValueChanged(app, event)
           % value = app.RecieverCoordsHeigth.Value;
        end

        % Value changed function: absorption1
        function absorption1ValueChanged(app, event)
            absorb1 = app.absorption1.Value;
        end

        % Value changed function: absorption2
        function absorption2ValueChanged(app, event)
            absorb2 = app.absorption2.Value;
        end

        % Value changed function: absorption3
        function absorption3ValueChanged(app, event)
            absorb3 = app.absorption3.Value;
        end

        % Value changed function: absorption4
        function absorption4ValueChanged(app, event)
            absorb4 = app.absorption4.Value;
        end

        % Value changed function: absorption5
        function absorption5ValueChanged(app, event)
            absorb5 = app.absorption5.Value;
        end

        % Value changed function: absorption6
        function absorption6ValueChanged(app, event)
            absorb6 = app.absorption6.Value;
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            app_1;
            closereq;
        end

        % Menu selected function: ProcessstimuliMenu
        function ProcessstimuliMenuSelected(app, event)
            app_3;
            closereq;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            closereq;
        end

        % Value changed function: distanceMax
        function distanceMaxValueChanged(app, event)
            maxDistance = app.distanceMax.Value;
        end

        % Value changed function: distanceMin
        function distanceMinValueChanged(app, event)
            minDistance = app.distanceMin.Value;
        end

        % Value changed function: step
        function stepValueChanged(app, event)
            steps = app.step.Value;
        end

        % Value changed function: bearing
        function bearingValueChanged(app, event)
            Bearing = app.bearing.Value;
        end

        % Value changed function: irLength
        function irLengthValueChanged(app, event)
            ir_length = app.irLength.Value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.HomeMenu = uimenu(app.Menu);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.ProcessstimuliMenu = uimenu(app.Menu);
            app.ProcessstimuliMenu.MenuSelectedFcn = createCallbackFcn(app, @ProcessstimuliMenuSelected, true);
            app.ProcessstimuliMenu.Text = 'Process stimuli';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SelectDirectoryToSaveBRIRButton = uibutton(app.UIFigure, 'push');
            app.SelectDirectoryToSaveBRIRButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryToSaveBRIRButtonPushed, true);
            app.SelectDirectoryToSaveBRIRButton.Text = 'Select Directory to save BRIR';
            app.RoomDimentionLabel = uilabel(app.UIFigure);
            app.RoomDimentionLabel.Text = 'Room Dimention: ';
            app.RoomDimentionLength = uieditfield(app.UIFigure, 'numeric');
            app.RoomDimentionLength.ValueChangedFcn = createCallbackFcn(app, @RoomDimentionLengthValueChanged, true);
            app.RoomDimentionlWidth = uieditfield(app.UIFigure, 'numeric');
            app.RoomDimentionlWidth.ValueChangedFcn = createCallbackFcn(app, @RoomDimentionlWidthValueChanged, true);
            app.RoomDimentionlHeigth = uieditfield(app.UIFigure, 'numeric');
            app.RoomDimentionlHeigth.ValueChangedFcn = createCallbackFcn(app, @RoomDimentionlHeigthValueChanged, true);
            app.GenerateBRIRButton = uibutton(app.UIFigure, 'push');
            app.GenerateBRIRButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateBRIRButtonPushed, true);
            app.GenerateBRIRButton.Text = 'Generate BRIR';
            app.test1Button = uibutton(app.UIFigure, 'push');
            app.test1Button.ButtonPushedFcn = createCallbackFcn(app, @test1ButtonPushed, true);
            app.test1Button.Text = 'test1';
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            app.WidthLabel = uilabel(app.UIFigure);
            app.WidthLabel.Text = 'Width';
            app.HeigthLabel = uilabel(app.UIFigure);
            app.HeigthLabel.Text = 'Heigth';
            app.RecieverCoordinatesLabel = uilabel(app.UIFigure);
            app.RecieverCoordinatesLabel.Text = 'Reciever Coordinates';
            app.RecieverCoordsLength = uieditfield(app.UIFigure, 'numeric');
            app.RecieverCoordsLength.ValueChangedFcn = createCallbackFcn(app, @RecieverCoordsLengthValueChanged, true);
            app.RecieverCoordsWidth = uieditfield(app.UIFigure, 'numeric');
            app.RecieverCoordsWidth.ValueChangedFcn = createCallbackFcn(app, @RecieverCoordsWidthValueChanged, true);
            app.RecieverCoordsHeigth = uieditfield(app.UIFigure, 'numeric');
            app.RecieverCoordsHeigth.ValueChangedFcn = createCallbackFcn(app, @RecieverCoordsHeigthValueChanged, true);
            app.Logo = uibutton(app.UIFigure, 'push');
            app.Logo.Icon = 'Anglia_Ruskin_University_logo.png';
            app.Logo.Tooltip = {'dfs '};
            app.Logo.Text = '';
            app.AbsorptionLabel = uilabel(app.UIFigure);
            app.AbsorptionLabel.Text = 'Absorption';
            app.absorption1 = uieditfield(app.UIFigure, 'numeric');
            app.absorption1.ValueChangedFcn = createCallbackFcn(app, @absorption1ValueChanged, true);
            app.absorption2 = uieditfield(app.UIFigure, 'numeric');
            app.absorption2.ValueChangedFcn = createCallbackFcn(app, @absorption2ValueChanged, true);
            app.absorption3 = uieditfield(app.UIFigure, 'numeric');
            app.absorption3.ValueChangedFcn = createCallbackFcn(app, @absorption3ValueChanged, true);
            app.absorption4 = uieditfield(app.UIFigure, 'numeric');
            app.absorption4.ValueChangedFcn = createCallbackFcn(app, @absorption4ValueChanged, true);
            app.absorption5 = uieditfield(app.UIFigure, 'numeric');
            app.absorption5.ValueChangedFcn = createCallbackFcn(app, @absorption5ValueChanged, true);
            app.absorption6 = uieditfield(app.UIFigure, 'numeric');
            app.absorption6.ValueChangedFcn = createCallbackFcn(app, @absorption6ValueChanged, true);
            app.BearingLabel = uilabel(app.UIFigure);
            app.BearingLabel.Text = 'Bearing';
            app.bearing = uieditfield(app.UIFigure, 'numeric');
            app.bearing.ValueChangedFcn = createCallbackFcn(app, @bearingValueChanged, true);
            app.DistanceLabel = uilabel(app.UIFigure);
            app.DistanceLabel.Text = 'Distance';
            app.distanceMax = uieditfield(app.UIFigure, 'numeric');
            app.distanceMax.ValueChangedFcn = createCallbackFcn(app, @distanceMaxValueChanged, true);
            app.MaximumLabel = uilabel(app.UIFigure);
            app.MaximumLabel.Text = 'Maximum';
            app.distanceMin = uieditfield(app.UIFigure, 'numeric');
            app.distanceMin.ValueChangedFcn = createCallbackFcn(app, @distanceMinValueChanged, true);
            app.step = uieditfield(app.UIFigure, 'numeric');
            app.step.ValueChangedFcn = createCallbackFcn(app, @stepValueChanged, true);
            app.MinimumLabel = uilabel(app.UIFigure);
            app.MinimumLabel.Text = 'Minimum';
            app.StepLabel = uilabel(app.UIFigure);
            app.StepLabel.Text = 'Step';
            app.IRLengthLabel = uilabel(app.UIFigure);
            app.IRLengthLabel.Text = 'IR Length';
            app.irLength = uieditfield(app.UIFigure, 'numeric');
            app.irLength.ValueChangedFcn = createCallbackFcn(app, @irLengthValueChanged, true);
            app.brirGraph = uiaxes(app.UIFigure);
            title(app.brirGraph, 'BRIR')
            xlabel(app.brirGraph, 'Samples')
            ylabel(app.brirGraph, 'Amplitude')
            app.brirNum = uilabel(app.UIFigure);
            app.brirNum.Text = 'Waiting to generate BRIR.';
        end
    end

    methods (Access = public)

        % Construct app
        function app = generateBrir

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end