classdef processStimuli < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Stimuli                      matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        HomeMenu                     matlab.ui.container.Menu
        GenerateBRIRMenu             matlab.ui.container.Menu
        ExitMenu                     matlab.ui.container.Menu
        SelectUnproccessedStimuliButton  matlab.ui.control.Button
        Logo                         matlab.ui.control.Button
        SelecDirectoryforBRIRButton  matlab.ui.control.Button
        unproccessedStimuli          matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        ProcessStimuliButton         matlab.ui.control.Button
    end

    
    properties (Access = public)
        file % unproccessed stimuli
        path % path to unproccesed stimuli
        unproStimuli %path + file
        y %samples in the unproccessed stimuli
        brir_dir %directory of the brir's
        dirinfo %the dir info in brir directory
        numfiles % number of wav files in brir directory
        brirWav %current BRIR wav file
    end
    

    methods (Access = private)

        % Button pushed function: SelectUnproccessedStimuliButton
        function SelectUnproccessedStimuliButtonPushed(app, event)
            %Get unproccessed stimuli and extract samples
            [app.file, app.path] = uigetfile('*.wav');
            app.unproStimuli = [app.path, app.file];
            [app.y, Fs] = audioread(app.unproStimuli);
            %for TESTing only
            fprintf("%s\", app.unproStimuli);
            plot(app.unproccessedStimuli,app.y);
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            SoundEngine;
            closereq;
        end

        % Menu selected function: GenerateBRIRMenu
        function GenerateBRIRMenuSelected(app, event)
            generateBrir;
            closereq;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            closereq;
        end

        % Button pushed function: SelecDirectoryforBRIRButton
        function SelecDirectoryforBRIRButtonPushed(app, event)
            %get the directory of the brir
            app.brir_dir = uigetdir('C:\', 'Select the directory on the BRIR')+("\");
            %get the dir info from the brir directory
            app.dirinfo = dir(fullfile(app.brir_dir, '*.wav'));
            app.numfiles = length(app.dirinfo);
            sortDir = natsortfiles({app.dirinfo.name});
            
            %For TEST ONLY
            for i = 1:app.numfiles
                app.brirWav = char(sortDir(i));
                fprintf("%s\n", app.brirWav);
            end

        end

        % Button pushed function: ProcessStimuliButton
        function ProcessStimuliButtonPushed(app, event)
            %TODO
            %iterrate through the number of brir's, 
            %read the current BRIR
            %assign the the channels to properties
            %convolve with the unprocessed timuli
            %collect the right ammount of samples 
            %process the ramp up / ramp down and stich together the samples
            %plot the unprocess stimuli to see progression
            %write the new audio file as a wav
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Stimuli = uifigure;
            app.Stimuli.Name = 'Stimuli';
            app.Menu = uimenu(app.Stimuli);
            app.Menu.Text = 'Menu';
            app.HomeMenu = uimenu(app.Menu);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.GenerateBRIRMenu = uimenu(app.Menu);
            app.GenerateBRIRMenu.MenuSelectedFcn = createCallbackFcn(app, @GenerateBRIRMenuSelected, true);
            app.GenerateBRIRMenu.Text = 'Generate BRIR';
            app.ExitMenu = uimenu(app.Menu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.SelectUnproccessedStimuliButton = uibutton(app.Stimuli, 'push');
            app.SelectUnproccessedStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @SelectUnproccessedStimuliButtonPushed, true);
            app.SelectUnproccessedStimuliButton.Text = 'Select Unproccessed Stimuli';
            app.Logo = uibutton(app.Stimuli, 'push');
            app.Logo.Icon = 'Anglia_Ruskin_University_logo.png';
            app.Logo.Tooltip = {'dfs '};
            app.Logo.Text = '';
            app.SelecDirectoryforBRIRButton = uibutton(app.Stimuli, 'push');
            app.SelecDirectoryforBRIRButton.ButtonPushedFcn = createCallbackFcn(app, @SelecDirectoryforBRIRButtonPushed, true);
            app.SelecDirectoryforBRIRButton.Text = 'Selec Directory for BRIR';
            app.unproccessedStimuli = uiaxes(app.Stimuli);
            title(app.unproccessedStimuli, 'Unproccessed Stimuli')
            xlabel(app.unproccessedStimuli, 'Samples')
            ylabel(app.unproccessedStimuli, 'Amplitude')
            app.UIAxes2 = uiaxes(app.Stimuli);
            title(app.UIAxes2, 'Proccessed Stimuli')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ProcessStimuliButton = uibutton(app.Stimuli, 'push');
            app.ProcessStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessStimuliButtonPushed, true);
            app.ProcessStimuliButton.Text = 'Process Stimuli';
        end
    end

    methods (Access = public)

        % Construct app
        function app = processStimuli

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Stimuli)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Stimuli)
        end
    end
end